classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UploadButton                    matlab.ui.control.Button
        filter                          matlab.ui.control.UIAxes
        OriginalImagePanel              matlab.ui.container.Panel
        original1                       matlab.ui.control.UIAxes
        OperationsDropDownLabel         matlab.ui.control.Label
        OperationsDropDown              matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        SpatialTab                      matlab.ui.container.Tab
        RemovingSaltPepperNoisePanel    matlab.ui.container.Panel
        SandP                           matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        MedianFilterButton              matlab.ui.control.Button
        KernelSizeEditFieldLabel        matlab.ui.control.Label
        KernelSizeEditField             matlab.ui.control.NumericEditField
        ofpixelscorruptionEditFieldLabel  matlab.ui.control.Label
        pixels_corruption               matlab.ui.control.NumericEditField
        EdgeDetectionPanel              matlab.ui.container.Panel
        SobelLabel                      matlab.ui.control.Label
        HButton                         matlab.ui.control.Button
        VButton                         matlab.ui.control.Button
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        EdgeDetectionandSmoothingPanel  matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        LaplaceButton                   matlab.ui.control.Button
        alphaEditField_5Label           matlab.ui.control.Label
        alphaEditField_5                matlab.ui.control.NumericEditField
        fourierTab                      matlab.ui.container.Tab
        PeriodicNoisePanel              matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        XCyclesEditFieldLabel           matlab.ui.control.Label
        XCyclesEditField                matlab.ui.control.NumericEditField
        NoiseDirectionDropDownLabel     matlab.ui.control.Label
        NoiseDirectionDropDown          matlab.ui.control.DropDown
        YCyclesEditFieldLabel           matlab.ui.control.Label
        YCyclesEditField                matlab.ui.control.NumericEditField
        BandRejectandNotchFilterPanel   matlab.ui.container.Panel
        Label_5                         matlab.ui.control.Label
        BandRejectButton                matlab.ui.control.Button
        NotchButton                     matlab.ui.control.Button
        MaskFilterPanel                 matlab.ui.container.Panel
        MaskButton                      matlab.ui.control.Button
        filter_2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        disp_img % image after changing it to gray scare 
        SP_img % salt and prepper image
  
        periodic % Description
        disp_img2 % I was just trying to make sure the picture is the same when applying filters 
        
        disp_img3 % I was just trying to make sure the picture is the same when applying fourier 
    end


    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            drawnow;   % To draw instantly, to fix the Alt + Tab effect
            figure(app.UIFigure);
            [filename,pathname] = uigetfile({'.jpg';'.png';'.bmp';'.oct'},"open file");

            fullname = strcat(pathname,filename);
            if fullname 
            original_image = imread(fullname);
            %handling if the user chose a colored image 
            app.disp_img=rgb2gray(original_image);
            app.disp_img2=rgb2gray(original_image);
            app.disp_img3=rgb2gray(original_image);
            imshow(app.disp_img,"parent",app.original1);
            
            else
                f = errordlg('please choose another a picture','File Error');
                drawnow;   % To draw instantly, to fix the Alt + Tab effect
            end
            drawnow;   % To draw instantly, to fix the Alt + Tab effect
            figure(app.UIFigure);
        end

        % Value changed function: OperationsDropDown
        function OperationsDropDownValueChanged(app, event)
            value = app.OperationsDropDown.Value;
             switch value
                case "Histogram"
                     [counts, binLocations] = imhist(app.disp_img);
                     imshow(app.disp_img,"parent",app.original1);

                     bar(app.filter_2,binLocations,counts);
                    
                 case "Histogram_EQ"
                     
                    eq_image = histeq(app.disp_img);
                    imshow(eq_image,"parent",app.original1);

                     [counts, binLocations] = imhist(eq_image);
                     stem(app.filter_2,binLocations,counts);


                 case "Fourier"
                      image_fourier=fftshift(fft2(app.disp_img));
                        fl = log(1+abs(image_fourier));
                        fm = max(fl(:));
                        log_img=im2uint8(fl/fm);
                      imshow(log_img,"parent",app.original1);
                      cla(app.filter_2)
                
                 case "Original"
                     imshow(app.disp_img,"parent",app.original1);
                     cla(app.filter_2)
            end
        end

        % Button pushed function: SandP
        function SandPButtonPushed(app, event)
            
        %% Salt and pepper 
        corr=0.2; %default corruption %
        corr=app.pixels_corruption.Value; %value entered by user
        salt_pepper_image = imnoise(app.disp_img2,'salt & pepper',corr);
        app.SP_img=salt_pepper_image;

        imshow(salt_pepper_image,"parent",app.filter);
        end

        % Value changed function: pixels_corruption
        function pixels_corruptionValueChanged(app, event)
            value = app.pixels_corruption.Value;
    
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
        kernel_size=3; %default kernel size
        kernel_size=app.KernelSizeEditField.Value; %kernel size entered b user
        medina_filt_img = medfilt2(app.SP_img, [kernel_size,kernel_size]);
        imshow(medina_filt_img,"parent",app.filter);

        end

        % Value changed function: KernelSizeEditField
        function KernelSizeEditFieldValueChanged(app, event)
            value = app.KernelSizeEditField.Value;
            
        end

        % Button pushed function: LaplaceButton
        function LaplaceButtonPushed(app, event)
            alpha_value=0.2; %efault aplha value
            alpha_value=app.alphaEditField_5.Value;%value enetered by user
            lap=fspecial('laplacian',app.alphaEditField_5.Value);
            lap_img=filter2(lap,app.disp_img2)/50;
            imshow(lap_img,"parent",app.filter);
        end

        % Value changed function: alphaEditField_5
        function alphaEditField_5ValueChanged(app, event)
            value = app.alphaEditField_5.Value;
            
        end

        % Callback function
        function SobelButtonPushed(app, event)

        
        end

        % Button pushed function: HButton
        function HButtonPushed(app, event)
         %user applying sobel horizontally
         threshold=0;
         threshold=app.ThresholdEditField.Value;
         sobel_img=edge(app.disp_img2,'sobel',threshold,'horizontal');
         imshow(sobel_img,"parent",app.filter);
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
          %user applying sobel vertically
         threshold=0;
         threshold=app.ThresholdEditField.Value;
         sobel_img=edge(app.disp_img2,'sobel',threshold,'vertical');
         imshow(sobel_img,"parent",app.filter);
        end

        % Value changed function: XCyclesEditField
        function XCyclesEditFieldValueChanged(app, event)
            value = app.XCyclesEditField.Value;
            
        end

        % Value changed function: NoiseDirectionDropDown
        function NoiseDirectionDropDownValueChanged(app, event)
            value = app.NoiseDirectionDropDown.Value;
            switch value
                case "X_sin"        
                    s = size(app.disp_img3);
                    [x, y]= meshgrid(1:s(2),1:s(1));
                    cycles_no=1;
                    cycles_no=app.XCyclesEditField.Value;
                    n =cycles_no;
                    Wx = max(max(x));
                    fx = n/Wx;
                    px = sin(2*pi*fx*x)+1;
                    periodic_img= mat2gray((im2double(app.disp_img)+px));
                    imshow(periodic_img,"parent",app.filter);
                    app.periodic=periodic_img;

                case "X_cos"
                    s= size(app.disp_img3);
                    [x, y]= meshgrid(1:s(2),1:s(1));
                    cycles_no=1;
                    cycles_no=app.XCyclesEditField.Value;
                    n =cycles_no;
                    Wx = max(max(x));
                    fx = n/Wx;
                    px = cos(2*pi*fx*x)+1;
                    periodic_img = mat2gray((im2double(app.disp_img)+px));
                    imshow(periodic_img,"parent",app.filter);
                    app.periodic=periodic_img;

                 case "Y_sin"
                     s = size(app.disp_img3);
                    [x, y]= meshgrid(1:s(2),1:s(1));
                    cycles_no=1;
                    cycles_no=app.YCyclesEditField.Value;
                    n =cycles_no;
                    Wy = max(max(y));
                    fy = n/Wy;
                    py = sin(2*pi*fy*y)+1;
                    periodic_img = mat2gray((im2double(app.disp_img)+py));
                    imshow(periodic_img,"parent",app.filter);
                    app.periodic=periodic_img;

                   case "Y_cos"
                    s = size(app.disp_img3);
                    [x, y]= meshgrid(1:s(2),1:s(1));
                    cycles_no=1;
                    cycles_no=app.YCyclesEditField.Value;
                    n =cycles_no;
                    Wy = max(max(y));
                    fy = n/Wy;
                    py = cos(2*pi*fy*y)+1;
                    periodic_img = mat2gray((im2double(app.disp_img)+py));
                    imshow(periodic_img,"parent",app.filter)
                    app.periodic=periodic_img;

                case "XY_sin"
                    s = size(app.disp_img3);
                    [x, y]= meshgrid(1:s(2),1:s(1));
                    xcycles_no=1;
                    xcycles_no=app.XCyclesEditField.Value;
                    nx =xcycles_no;
                    ycycles_no=1;
                    ycycles_no=app.YCyclesEditField.Value;
                    ny =ycycles_no;


                    Wx = max(max(x));
                    Wy = max(max(y));
                    fx = nx/Wx;
                    fy = ny/Wy;
                    pxy = sin(2*pi*fx*x + 2*pi*fy*y)+1; 
                    periodic_img = mat2gray((im2double(app.disp_img)+pxy));
                    imshow(periodic_img,"parent",app.filter);
                    app.periodic=periodic_img;
                 case "XY_cos"
                    s = size(app.disp_img3);
                    [x, y]= meshgrid(1:s(2),1:s(1));
                    xcycles_no=1;
                    xcycles_no=app.XCyclesEditField.Value;
                    nx =xcycles_no;
                    ycycles_no=1;
                    ycycles_no=app.YCyclesEditField.Value;
                    ny =ycycles_no;


                    Wx = max(max(x));
                    Wy = max(max(y));
                    fx = nx/Wx;
                    fy = ny/Wy;
                    pxy = cos(2*pi*fx*x + 2*pi*fy*y)+1; 
                    periodic_img = mat2gray((im2double(app.disp_img)+pxy));
                    imshow(periodic_img,"parent",app.filter);
                    app.periodic=periodic_img;


            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            
        end

        % Button pushed function: BandRejectButton
        function BandRejectButtonPushed(app, event)

		%getting fouier of periodic noisy image
		fftimage = fftshift(fft2(app.periodic));
                % getting the absolute of the periodic noisy image
		fa = abs(fftimage);
		fm = max(fa(:));
		absFFT_Image = fa/fm;
		%applying a threshold using trial and error technique 
		[x, y] = find(absFFT_Image >= 0.2 & absFFT_Image < 0.9);
                
		[size_x,size_y] = size(app.periodic); %getting the size of the noisy mage
  
                %making a circle of that size
		[circx,circy] = meshgrid(-size_y/2 : size_y/2-1 , -size_x/2 : size_x/2-1);
		z = sqrt(circx.^2+circy.^2);
                %applying the circle on the spikes 
		circleAtSpikes = z(x,y);
		
		band_reject = (z > circleAtSpikes(1)+1 | z < circleAtSpikes(2)-1);
                %multiplying the noisy image with band reject
		fftimageNEW = fftimage.*band_reject;
		
		FilteredFromNoise = fftimageNEW;
  
                %inverse fourier to return to the original image
		FilteredImage = ifft2(ifftshift(FilteredFromNoise));
                imshow(FilteredImage,"parent",app.filter);


        end

        % Button pushed function: NotchButton
        function NotchButtonPushed(app, event)
            %getting fourier of the noisy image
            fftimage = fftshift(fft2(app.periodic));
             %getting the absolute of noisy image
            fa = abs(fftimage);
		fm = max(fa(:));
		absFFT_Image = fa/fm;

                %getting the spikes using trial and error technique
		[x, y] = find(absFFT_Image >= 0.2 & absFFT_Image < 0.9);
                 %making the rows and columns of spikes equal to zero
		fftimage(x(1),:) = 0;
		fftimage(x(2),:) = 0;
		fftimage(:,y(1)) = 0;
		fftimage(:,y(2)) = 0;
	
                %multiplying the noisy image with notch filter

		FilteredFromNoise = fftimage;
		FilteredImage = ifft2(ifftshift(FilteredFromNoise));
  %brighting the image a little bit
                final=FilteredImage/0.4;
                imshow(final,"parent",app.filter);
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
      %applying fourier on the periodic image 
            periodic_fourier=fftshift(fft2(app.periodic));

        %getting the absolute of the fourier of noisy image
        magImageNoisy = log(abs(periodic_fourier));
        %making a mask on the same size with values equal to ones 
            
        mask = ones(size(magImageNoisy));

        %log of fourier  of the noisy image in another window to choose the nosiy pixel from 
        fl = log(1+abs(periodic_fourier));
        fm = max(fl(:));
        log_img=im2uint8(fl/fm);
        figure,
        imshow(log_img),impixelinfo;
        %giving the option to user to zoom in
        zoom on;
        %zoom s paused when user clicks on space bar 
        pause()
        zoom off; 
        %getting the coordinates of two points using ginput
        [X,Y]=ginput(2);
        zoom out;
        %renaming array X with pixel1
        %renaming array Y with pixel2
        pixel1=X;
        pixel2=Y;
        x1 = int64(pixel1(1));
        y1 = int64(pixel2(1)); 
        x2 = int64(pixel1(2));
        y2 = int64(pixel2(2));


        x2_final=x2;

        y2_final=y2;
        %equating the coordinates chosen by user to 0 (pixls are now black)
        mask(x1,y1)=0;
        mask(x2_final,y2_final)=0;
      
        %multiplying the noisy image with the mask
        filtered = mask.*periodic_fourier;
        %applying inverse fourier and returning the original image
        filteredImage = ifft2(ifftshift(filtered));
        ampFilteredImage = abs(filteredImage);
        imshow(ampFilteredImage,'parent',app.filter);
        end

        % Value changed function: YCyclesEditField
        function YCyclesEditFieldValueChanged(app, event)
            value = app.YCyclesEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.filter = uiaxes(app.UIFigure);
            app.filter.XTick = [];
            app.filter.YTick = [];
            app.OriginalImagePanel = uipanel(app.UIFigure);
            app.OriginalImagePanel.Title = 'Original Image';
            app.original1 = uiaxes(app.OriginalImagePanel);
            app.original1.XTick = [];
            app.original1.YTick = [];
            app.OperationsDropDownLabel = uilabel(app.OriginalImagePanel);
            app.OperationsDropDownLabel.Text = 'Operations';
            app.OperationsDropDown = uidropdown(app.OriginalImagePanel);
            app.OperationsDropDown.Items = {'Original', 'Histogram', 'Histogram_EQ', 'Fourier'};
            app.OperationsDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationsDropDownValueChanged, true);
            app.OperationsDropDown.Value = 'Original';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SpatialTab = uitab(app.TabGroup);
            app.SpatialTab.Title = 'Spatial';
            app.RemovingSaltPepperNoisePanel = uipanel(app.SpatialTab);
            app.RemovingSaltPepperNoisePanel.Title = 'Removing Salt & Pepper Noise';
            app.SandP = uibutton(app.RemovingSaltPepperNoisePanel, 'push');
            app.SandP.ButtonPushedFcn = createCallbackFcn(app, @SandPButtonPushed, true);
            app.SandP.Text = 'Salt & Pepper Noise';
            app.Label = uilabel(app.RemovingSaltPepperNoisePanel);
            app.Label.Text = '';
            app.MedianFilterButton = uibutton(app.RemovingSaltPepperNoisePanel, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.KernelSizeEditFieldLabel = uilabel(app.RemovingSaltPepperNoisePanel);
            app.KernelSizeEditFieldLabel.Text = 'Kernel Size';
            app.KernelSizeEditField = uieditfield(app.RemovingSaltPepperNoisePanel, 'numeric');
            app.KernelSizeEditField.Limits = [1 Inf];
            app.KernelSizeEditField.ValueChangedFcn = createCallbackFcn(app, @KernelSizeEditFieldValueChanged, true);
            app.KernelSizeEditField.Value = 3;
            app.ofpixelscorruptionEditFieldLabel = uilabel(app.RemovingSaltPepperNoisePanel);
            app.ofpixelscorruptionEditFieldLabel.Text = '% of pixels corruption';
            app.pixels_corruption = uieditfield(app.RemovingSaltPepperNoisePanel, 'numeric');
            app.pixels_corruption.Limits = [0 1];
            app.pixels_corruption.ValueChangedFcn = createCallbackFcn(app, @pixels_corruptionValueChanged, true);
            app.pixels_corruption.Value = 0.2;
            app.EdgeDetectionPanel = uipanel(app.SpatialTab);
            app.EdgeDetectionPanel.Title = 'Edge Detection';
            app.SobelLabel = uilabel(app.EdgeDetectionPanel);
            app.SobelLabel.Text = 'Sobel';
            app.HButton = uibutton(app.EdgeDetectionPanel, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.HButton.Text = 'H';
            app.VButton = uibutton(app.EdgeDetectionPanel, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.ThresholdEditFieldLabel = uilabel(app.EdgeDetectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.EdgeDetectionPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.EdgeDetectionandSmoothingPanel = uipanel(app.SpatialTab);
            app.EdgeDetectionandSmoothingPanel.Title = 'Edge Detection and Smoothing';
            app.Label_3 = uilabel(app.EdgeDetectionandSmoothingPanel);
            app.Label_3.Text = '';
            app.LaplaceButton = uibutton(app.EdgeDetectionandSmoothingPanel, 'push');
            app.LaplaceButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceButtonPushed, true);
            app.LaplaceButton.Text = 'Laplace';
            app.alphaEditField_5Label = uilabel(app.EdgeDetectionandSmoothingPanel);
            app.alphaEditField_5Label.Text = 'alpha';
            app.alphaEditField_5 = uieditfield(app.EdgeDetectionandSmoothingPanel, 'numeric');
            app.alphaEditField_5.Limits = [0 1];
            app.alphaEditField_5.ValueChangedFcn = createCallbackFcn(app, @alphaEditField_5ValueChanged, true);
            app.alphaEditField_5.Value = 0.2;
            app.fourierTab = uitab(app.TabGroup);
            app.fourierTab.Title = 'fourier';
            app.PeriodicNoisePanel = uipanel(app.fourierTab);
            app.PeriodicNoisePanel.Title = 'Periodic Noise';
            app.Label_4 = uilabel(app.PeriodicNoisePanel);
            app.Label_4.Text = '';
            app.XCyclesEditFieldLabel = uilabel(app.PeriodicNoisePanel);
            app.XCyclesEditFieldLabel.Text = 'X Cycles';
            app.XCyclesEditField = uieditfield(app.PeriodicNoisePanel, 'numeric');
            app.XCyclesEditField.Limits = [0 Inf];
            app.XCyclesEditField.ValueChangedFcn = createCallbackFcn(app, @XCyclesEditFieldValueChanged, true);
            app.XCyclesEditField.Value = 1;
            app.NoiseDirectionDropDownLabel = uilabel(app.PeriodicNoisePanel);
            app.NoiseDirectionDropDownLabel.Text = 'Noise Direction';
            app.NoiseDirectionDropDown = uidropdown(app.PeriodicNoisePanel);
            app.NoiseDirectionDropDown.Items = {'X_sin', 'X_cos', 'Y_sin', 'Y_cos', 'XY_sin', 'XY_cos'};
            app.NoiseDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseDirectionDropDownValueChanged, true);
            app.NoiseDirectionDropDown.Value = 'X_sin';
            app.YCyclesEditFieldLabel = uilabel(app.PeriodicNoisePanel);
            app.YCyclesEditFieldLabel.Text = 'Y Cycles';
            app.YCyclesEditField = uieditfield(app.PeriodicNoisePanel, 'numeric');
            app.YCyclesEditField.Limits = [0 Inf];
            app.YCyclesEditField.ValueChangedFcn = createCallbackFcn(app, @YCyclesEditFieldValueChanged, true);
            app.YCyclesEditField.Value = 1;
            app.BandRejectandNotchFilterPanel = uipanel(app.fourierTab);
            app.BandRejectandNotchFilterPanel.Title = 'Band Reject and Notch Filter';
            app.Label_5 = uilabel(app.BandRejectandNotchFilterPanel);
            app.Label_5.Text = '';
            app.BandRejectButton = uibutton(app.BandRejectandNotchFilterPanel, 'push');
            app.BandRejectButton.ButtonPushedFcn = createCallbackFcn(app, @BandRejectButtonPushed, true);
            app.BandRejectButton.Text = 'Band Reject';
            app.NotchButton = uibutton(app.BandRejectandNotchFilterPanel, 'push');
            app.NotchButton.ButtonPushedFcn = createCallbackFcn(app, @NotchButtonPushed, true);
            app.NotchButton.Text = 'Notch';
            app.MaskFilterPanel = uipanel(app.fourierTab);
            app.MaskFilterPanel.Title = 'Mask Filter';
            app.MaskButton = uibutton(app.MaskFilterPanel, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.filter_2 = uiaxes(app.UIFigure);
            app.filter_2.XTick = [];
            app.filter_2.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end